classdef AudioSignalProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout3            matlab.ui.container.GridLayout
        StatusPanel            matlab.ui.container.Panel
        GridLayout4            matlab.ui.container.GridLayout
        StatusArea             matlab.ui.control.TextArea
        TabGroup_2             matlab.ui.container.TabGroup
        MergeTab               matlab.ui.container.Tab
        MergeGr                matlab.ui.control.UIAxes
        SaveLogasButton        matlab.ui.control.Button
        SaveLogButton          matlab.ui.control.Button
        AudioOperationPanel    matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        ExportButton           matlab.ui.control.Button
        MergeButton            matlab.ui.control.Button
        StopButton             matlab.ui.control.Button
        PauseResumeButton      matlab.ui.control.StateButton
        PlayButton             matlab.ui.control.Button
        TabGroup               matlab.ui.container.TabGroup
        TimeDomainTab          matlab.ui.container.Tab
        TimeGr                 matlab.ui.control.UIAxes
        FrequencyDomainTab     matlab.ui.container.Tab
        FrGr                   matlab.ui.control.UIAxes
        AudioSettingPanel      matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        ReverseCheckBox        matlab.ui.control.CheckBox
        Sound                  matlab.ui.control.Knob
        InvertCheckBox         matlab.ui.control.CheckBox
        NormalizeCheckBox      matlab.ui.control.CheckBox
        AudioLength            matlab.ui.control.Label
        AudioLengthsLabel      matlab.ui.control.Label
        VolumnLabel            matlab.ui.control.Label
        AmplitudeSpinner       matlab.ui.control.Spinner
        AmplitudeSpinnerLabel  matlab.ui.control.Label
        SpeedSpinner           matlab.ui.control.Spinner
        SpeedSpinnerLabel      matlab.ui.control.Label
        AudioFilesPanel        matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        DeleteButton           matlab.ui.control.Button
        ImportButton           matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        al                     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusArea.Value = "Please import audio files.";
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            app.ListBox.Items(index) = [];
            app.ListBox.ItemsData(index) = [];
            cla(app.TimeGr);
            cla(app.MergeGr);
            cla(app.FrGr);
            if (isempty(app.ListBox.Items) ~= true)
                ListBoxValueChanged(app, event);
                %app.StatusArea.Value = "Please import audio files.";
            else
                app.StatusArea.Value = "Please import audio files.";
            end
            %if (isempty(app.ListBox.Items) = true)
            %    app.StatusArea.Value = "Please import audio files.";
            %end 
            %app.StatusArea.Value = "Please import audio files.";
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            clear y;
            [filename,filepath] = uigetfile('*.wav');  %open file
            filepath = strcat(filepath,filename);
            if (isempty(filepath) ~= true)
                [y, fs] = audioread(filepath);
                t = (0:length(y)) / fs;
                t(end) = [];
                app.al.Text = strcat(num2str(t(end))," Second");
                plot(app.TimeGr,t, y);
                h=zoom(app.TimeGr);
                h.Motion = 'horizontal';
                h.Enable = 'on';

                x = y;
                df = fs/length(x);
                f = -fs/2:df:fs/2-df;
                fftA = fftshift(fft(x))/length(fft(x));
                plot(app.FrGr, f, abs(fftA));
            
                itemList = app.ListBox.Items;
                itemData = app.ListBox.ItemsData;
                itemList{end+1} = filename;
                itemData{end+1} = filepath;
                app.ListBox.Items = itemList;
                app.ListBox.ItemsData = itemData;
            end
            %set(app.TimeInspect,'Enable','on');
            %set(app.FreqInspect,'Enable','on');
            app.StatusArea.Value = "Please select audio files.";
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            value = app.ListBox.ItemsData{index};
            app.StatusArea.Value = value;
            [y, fs] = audioread(value);
            t = (0:length(y)) / fs;
            t(end) = [];
            plot(app.TimeGr,t, y);
            
            x = y;
            df = fs/length(x);
            f = -fs/2:df:fs/2-df;
            fftA = fftshift(fft(x))/length(fft(x));
            plot(app.FrGr, f, abs(fftA));

            k = app.Sound.Value;
            global player
            player = audioplayer(y*k, fs);
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                [y, fs] = audioread(filePath{1});
                global maxAMP;
                maxAMP = max(abs(y));
                audioLength = length(y)/fs;
                set(app.AudioLength,'Text',string(audioLength))
                set(app.AudioSettingPanel,'Enable','on')
                set(app.InvertCheckBox,'Value',false)
                set(app.ReverseCheckBox,'Value',false)
                set(app.SpeedSpinner,'Value',1)
                set(app.AmplitudeSpinner,'Value',1)
                set(app.NormalizeCheckBox,'Value',false)
                %set(app.Switch,'Value',Off)
            else
                set(app.AudioSettingPanel,'Enable','off')
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global player
            play(player);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global player
            stop(player);
        end

        % Value changed function: PauseResumeButton
        function PauseResumeButtonValueChanged(app, event)
            value = app.PauseResumeButton.Value;
            global player
            if (value == 1) 
                pause(player);
            else
                resume(player);
            end
        end

        % Callback function
        function SliderValueChanged(app, event)
            global player
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            if (index ~= 0.0 && isplaying(player) ~= false)
                value = app.ListBox.ItemsData{index};
                pause(player);
                NewStart = get(player, 'CurrentSample')+1;
                stop(player);
                k = app.Slider.Value;
                [y, fs] = audioread(value);
                player = audioplayer(y*k, fs);
                play(player, NewStart);
            end
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            filePath = app.ListBox.Value;
            global player
            global mergeAudio
            global mergeFs
            if (length(filePath) <= 0)
                return
            end
            if (length(filePath) == 1)
                [y, fs] = audioread(filePath{1});
                t = (0:length(y)) / fs;
                t(end) = [];
                plot(app.MergeGr,t, y); 

                k = app.Sound.Value;
                player = audioplayer(y*k, fs);
                mergeAudio = y;
                mergeFs = fs;
                set(app.ExportButton,'Enable','on');
               return
            end
            
            for i = 2:length(filePath)
                if (i == 2)
                    [y1, fs1] = audioread(filePath{1});
                    
                    [y2, fs2] = audioread(filePath{2});
                    
                    lenY = size(y1, 1);
                    lenZ = size(y2, 1);
                    len = max(lenY, lenZ);
                    S = zeros(len, size(y1, 2));
                    S(1:lenY, :) = y1;
                    S(1:lenZ, :) = S(1:lenZ, :) + y2;
                    maxValue = max(abs(S(:)));
                    S = S / maxValue;
                    if (length(y1) >= length(y2))
                        t = (1:length(y1)) / fs1;
                        fs = fs1;
                    else
                        t = (1:length(y2)) / fs2;
                        fs = fs2;
                    end
                else
                    [y, fs_] = audioread(filePath{i});
                    y_dum = S;
                    fs_dum = fs;
                    
                    lenY = size(y_dum, 1);
                    lenZ = size(y, 1);
                    len = max(lenY, lenZ);
                    S = zeros(len, size(y_dum, 2));
                    S(1:lenY, :) = y_dum;
                    S(1:lenZ, :) = S(1:lenZ, :) + y;
                    maxValue = max(abs(S(:)));
                    S = S / maxValue;
                    if (length(y_dum) >= length(y))
                        t = (1:length(y_dum)) / fs;
                        fs = fs_dum;
                    else
                        t = (1:length(y)) / fs_;
                        fs = fs_;
                    end
                end
            end
            plot(app.MergeGr,t, S);
            Fs = fs;
            k = app.Sound.Value;
            player = audioplayer(S*k, Fs);
            mergeAudio = S;
            mergeFs = Fs;
            set(app.ExportButton,'Enable','on');
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.AudioSettingPanel,'Enable','off')
                if (app.ReverseCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = flipud(y);
                    audiowrite(filePath{1},y2,fs);
                end
                if (app.InvertCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = y * -1;
                    audiowrite(filePath{1},y2,fs);
                end
                [y, fs] = audioread(filePath{1});
                newSampleRate = app.SpeedSpinner.Value * fs;
                if (newSampleRate >= 80 && newSampleRate <= 1000000) 
                    y2 = y * app.AmplitudeSpinner.Value;
                    audiowrite(filePath{1},y2,fix(app.SpeedSpinner.Value * fs));
                end
                set(app.ListBox, 'Value', {});
            end           
            
        end

        % Button pushed function: SaveLogasButton
        function SaveLogasButtonPushed(app, event)
            filter = {'*.wav';'*.*'};
            [file, path] = uiputfile(filter);
            filetarget = strcat(path,file);
            
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.AudioSettingPanel,'Enable','off')
                [y, fs] = audioread(filePath{1});
                audiowrite(filetarget,y,fs);
                if (app.ReverseCheckBox.Value)
                    [y, fs] = audioread(filetarget);
                    y2 = flipud(y);
                    audiowrite(filetarget,y2,fs);
                end
                if (app.InvertCheckBox.Value)
                    [y, fs] = audioread(filetarget);
                    y2 = y * -1;
                    audiowrite(filetarget,y2,fs);
                    
                end
                [y, fs] = audioread(filetarget);
                newSampleRate = app.SpeedSpinner.Value * fs;
                if (newSampleRate >= 80 && newSampleRate <= 1000000) 
                    y2 = y * app.AmplitudeSpinner.Value;
                    audiowrite(filetarget,y2,fix(app.SpeedSpinner.Value * fs));
                end
                set(app.ListBox, 'Value', {});
                itemList = app.ListBox.Items;
                itemData = app.ListBox.ItemsData;
                itemList{end+1} = file;
                itemData{end+1} = filetarget;
                app.ListBox.Items = itemList;
                app.ListBox.ItemsData = itemData;
            end
        end

        % Callback function
        function NormalizeCheckBoxValueChanged(app, event)
            value = app.NormalizeCheckBox.Value;
            if (value)
                global maxAMP;
                newAMP = 1/max(maxAMP);
                set(app.AmplitudeSpinner,'Value',newAMP);
            else
                
            end
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            value = app.AmplitudeSpinner.Value;
            set(app.NormalizeCheckBox,'Value',false);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global mergeAudio
            global mergeFs
            filter = {'*.wav';'*.*'};
            [file, path] = uiputfile(filter);
            filetarget = strcat(path,file);
            audiowrite(filetarget,mergeAudio,mergeFs);
        end

        % Value changed function: Sound
        function SoundValueChanged(app, event)
            global player
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            if (index ~= 0.0 && isplaying(player) ~= false)
                value = app.ListBox.ItemsData{index};
                pause(player);
                NewStart = get(player, 'CurrentSample')+1;
                stop(player);
                k = app.Sound.Value;
                [y, fs] = audioread(value);
                player = audioplayer(y*k, fs);
                play(player, NewStart);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.al = uilabel(app.GridLayout3);
            app.al.Text = {''; ''};
            app.AudioFilesPanel = uipanel(app.GridLayout3);
            app.AudioFilesPanel.Title = 'Audio Files';
            app.GridLayout2 = uigridlayout(app.AudioFilesPanel);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.ImportButton = uibutton(app.GridLayout2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.DeleteButton = uibutton(app.GridLayout2, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.AudioSettingPanel = uipanel(app.GridLayout3);
            app.AudioSettingPanel.Enable = 'off';
            app.AudioSettingPanel.Title = 'Audio Setting';
            app.GridLayout2_2 = uigridlayout(app.AudioSettingPanel);
            app.SpeedSpinnerLabel = uilabel(app.GridLayout2_2);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.GridLayout2_2);
            app.SpeedSpinner.Step = 0.05;
            app.SpeedSpinner.Limits = [0.01 6];
            app.SpeedSpinner.Value = 1;
            app.AmplitudeSpinnerLabel = uilabel(app.GridLayout2_2);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.GridLayout2_2);
            app.AmplitudeSpinner.Step = 0.05;
            app.AmplitudeSpinner.Limits = [0 10];
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.AmplitudeSpinner.Value = 1;
            app.VolumnLabel = uilabel(app.GridLayout2_2);
            app.VolumnLabel.Text = 'Volumn';
            app.AudioLengthsLabel = uilabel(app.GridLayout2_2);
            app.AudioLengthsLabel.Text = 'AudioLength (s)';
            app.AudioLength = uilabel(app.GridLayout2_2);
            app.AudioLength.Text = '0';
            app.NormalizeCheckBox = uicheckbox(app.GridLayout2_2);
            app.NormalizeCheckBox.Text = 'Normalize Amplitude';
            app.InvertCheckBox = uicheckbox(app.GridLayout2_2);
            app.InvertCheckBox.Text = 'Invert Phase';
            app.Sound = uiknob(app.GridLayout2_2, 'continuous');
            app.Sound.Limits = [0 1];
            app.Sound.ValueChangedFcn = createCallbackFcn(app, @SoundValueChanged, true);
            app.ReverseCheckBox = uicheckbox(app.GridLayout2_2);
            app.ReverseCheckBox.Text = 'Reverse Audio';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time-Domain';
            app.TimeGr = uiaxes(app.TimeDomainTab);
            title(app.TimeGr, 'Time-Domain')
            xlabel(app.TimeGr, 'Time (Seconds)')
            ylabel(app.TimeGr, 'Amplitude')
            zlabel(app.TimeGr, 'Z')
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency-Domain';
            app.FrGr = uiaxes(app.FrequencyDomainTab);
            title(app.FrGr, 'Frequency-Domain')
            xlabel(app.FrGr, 'Frequency (Hz)')
            ylabel(app.FrGr, 'Magnitude |Y(f)|')
            zlabel(app.FrGr, 'Z')
            app.AudioOperationPanel = uipanel(app.GridLayout3);
            app.AudioOperationPanel.Title = 'Audio Operation';
            app.GridLayout = uigridlayout(app.AudioOperationPanel);
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseResumeButton = uibutton(app.GridLayout, 'state');
            app.PauseResumeButton.ValueChangedFcn = createCallbackFcn(app, @PauseResumeButtonValueChanged, true);
            app.PauseResumeButton.Text = 'Pause / Resume';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.MergeButton = uibutton(app.GridLayout, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge Audio Files';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export Merge Audio to .wav';
            app.SaveLogButton = uibutton(app.GridLayout3, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Text = 'Save Log';
            app.SaveLogasButton = uibutton(app.GridLayout3, 'push');
            app.SaveLogasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogasButtonPushed, true);
            app.SaveLogasButton.Text = 'Save Log as';
            app.TabGroup_2 = uitabgroup(app.GridLayout3);
            app.MergeTab = uitab(app.TabGroup_2);
            app.MergeTab.Title = 'Merge Audio';
            app.MergeGr = uiaxes(app.MergeTab);
            title(app.MergeGr, 'Merge Audio')
            xlabel(app.MergeGr, 'Time (Seconds)')
            ylabel(app.MergeGr, 'Amplitude')
            zlabel(app.MergeGr, 'Z')
            app.StatusPanel = uipanel(app.GridLayout3);
            app.StatusPanel.Title = 'Status';
            app.GridLayout4 = uigridlayout(app.StatusPanel);
            app.StatusArea = uitextarea(app.GridLayout4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AudioSignalProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end